classdef GSVET_CHINESE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigureGSVET              matlab.ui.Figure
        SavedDataImage             matlab.ui.control.Image
        NewDataImage               matlab.ui.control.Image
        Button                     matlab.ui.control.Button
        DataSim                    matlab.ui.control.Switch
        DataBackground             matlab.ui.control.Button
        TrialsNumberLabel          matlab.ui.control.Label
        TrialsNumberValue          matlab.ui.control.NumericEditField
        SimulationButton           matlab.ui.control.Button
        PanelModes                 matlab.ui.container.Panel
        SwitchMode                 matlab.ui.control.Switch
        SwitchDimension            matlab.ui.control.Switch
        Panel_4                    matlab.ui.container.Panel
        InitialSpoofingValue       matlab.ui.control.NumericEditField
        Label_5                    matlab.ui.control.Label
        SwitchSpoof                matlab.ui.control.ToggleSwitch
        Panel_3                    matlab.ui.container.Panel
        InitialMultipath           matlab.ui.control.NumericEditField
        Label_4                    matlab.ui.control.Label
        SwitchMultipath            matlab.ui.control.ToggleSwitch
        Panel_2                    matlab.ui.container.Panel
        InitialJammingValue        matlab.ui.control.NumericEditField
        Label_3                    matlab.ui.control.Label
        SwitchJamming              matlab.ui.control.ToggleSwitch
        Panel                      matlab.ui.container.Panel
        InitialCleanValue          matlab.ui.control.NumericEditField
        Label_2                    matlab.ui.control.Label
        SwitchClean                matlab.ui.control.ToggleSwitch
        PanelParaBackground        matlab.ui.container.Panel
        PanelParameters            matlab.ui.container.Panel
        Label_6                    matlab.ui.control.Label
        TapsValue                  matlab.ui.control.NumericEditField
        PowerAuthenticLabel        matlab.ui.control.Label
        PowerAuthenticValue        matlab.ui.control.NumericEditField
        PowerInteferenceLabel      matlab.ui.control.Label
        PowerInteferenceValue      matlab.ui.control.NumericEditField
        VectorsNumberLabel         matlab.ui.control.Label
        VectorsNumberValue         matlab.ui.control.NumericEditField
        ThermalNoiseValue          matlab.ui.control.NumericEditField
        ThermalNoiseLabel          matlab.ui.control.Label
        MultiAccessValue           matlab.ui.control.NumericEditField
        MultiAccessLabel           matlab.ui.control.Label
        TrueDelayValue             matlab.ui.control.NumericEditField
        TrueDelayLabel             matlab.ui.control.Label
        ErrorsSDLabel              matlab.ui.control.Label
        ErrorsSDValue              matlab.ui.control.NumericEditField
        ChipIntervalLabel          matlab.ui.control.Label
        ChipIntervalValue          matlab.ui.control.NumericEditField
        SetpointAGCLabel           matlab.ui.control.Label
        SetpointAGCValue           matlab.ui.control.NumericEditField
        AGCBandwidthLabel          matlab.ui.control.Label
        AGCBandwidthValue          matlab.ui.control.NumericEditField
        BandwidthPowerLabel        matlab.ui.control.Label
        BandwidthPowerValue        matlab.ui.control.NumericEditField
        AccumulationIntervalLabel  matlab.ui.control.Label
        AccumulationIntervalValue  matlab.ui.control.NumericEditField
        SymmetricDifferenceLabel   matlab.ui.control.Label
        SymmetricDifferenceValue   matlab.ui.control.NumericEditField
        CorrelatorDifferenceLabel  matlab.ui.control.Label
        CorrelatorDifferenceValue  matlab.ui.control.NumericEditField
        GNSSSIGNALISOLATIONVIAPOWERANDDISTORTIONDYNAMICMONTECARLOLabel  matlab.ui.control.Label
        PanelTitle                 matlab.ui.container.Panel
        Image                      matlab.ui.control.Image
        PercentValue               matlab.ui.control.NumericEditField
        PercentSign                matlab.ui.control.EditField
        Switch                     matlab.ui.control.Switch
        Label                      matlab.ui.control.Label
        USFLAG                     matlab.ui.control.Image
        CHINAFLAG                  matlab.ui.control.Image
        Evaluation                 matlab.ui.control.Button
        PanelGraph                 matlab.ui.container.Panel
        UIAxesGSVET                matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
app.UIFigureGSVET.Name = 'GSVET SOFTWARE - NUAA';
movegui(app.UIFigureGSVET, 'center');            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            if strcmp(value, 'ENGLISH')
                close (app.UIFigureGSVET);
                GSVET_ENGLISH;
            end
        end

        % Image clicked function: USFLAG
        function USFLAGImageClicked(app, event)
                close (app.UIFigureGSVET);
                GSVET_ENGLISH;            
        end

        % Value changed function: PercentValue
        function PercentValueValueChanged(app, event)
            app.PercentValue.Value;
        end

        % Value changed function: InitialCleanValue
        function InitialCleanValueValueChanged(app, event)
            app.InitialCleanValue.Value;
        end

        % Value changed function: InitialMultipath
        function InitialMultipathValueChanged(app, event)
            app.InitialMultipath.Value;
        end

        % Value changed function: InitialSpoofingValue
        function InitialSpoofingValueValueChanged(app, event)
            app.InitialSpoofingValue.Value;
        end

        % Value changed function: InitialJammingValue
        function InitialJammingValueValueChanged(app, event)
            app.InitialJammingValue.Value;
        end

        % Value changed function: TrialsNumberValue
        function TrialsNumberValueValueChanged(app, event)
            app.TrialsNumberValue.Value;
        end

        % Value changed function: VectorsNumberValue
        function VectorsNumberValueValueChanged(app, event)
            app.VectorsNumberValue.Value;
        end

        % Value changed function: ThermalNoiseValue
        function ThermalNoiseValueValueChanged(app, event)
            app.ThermalNoiseValue.Value;
        end

        % Value changed function: MultiAccessValue
        function MultiAccessValueValueChanged(app, event)
            app.MultiAccessValue.Value;
        end

        % Value changed function: TrueDelayValue
        function TrueDelayValueValueChanged(app, event)
            app.TrueDelayValue.Value;
        end

        % Value changed function: PowerAuthenticValue
        function PowerAuthenticValueValueChanged(app, event)
            app.PowerAuthenticValue.Value;
        end

        % Value changed function: PowerInteferenceValue
        function PowerInteferenceValueValueChanged(app, event)
            app.PowerInteferenceValue.Value;
        end

        % Value changed function: CorrelatorDifferenceValue
        function CorrelatorDifferenceValueValueChanged(app, event)
            app.CorrelatorDifferenceValue.Value;
        end

        % Value changed function: SymmetricDifferenceValue
        function SymmetricDifferenceValueValueChanged(app, event)
            app.SymmetricDifferenceValue.Value;
        end

        % Value changed function: AccumulationIntervalValue
        function AccumulationIntervalValueValueChanged(app, event)
            app.AccumulationIntervalValue.Value;
        end

        % Value changed function: BandwidthPowerValue
        function BandwidthPowerValueValueChanged(app, event)
            app.BandwidthPowerValue.Value;
        end

        % Value changed function: AGCBandwidthValue
        function AGCBandwidthValueValueChanged(app, event)
            app.AGCBandwidthValue.Value;
        end

        % Value changed function: SetpointAGCValue
        function SetpointAGCValueValueChanged(app, event)
            app.SetpointAGCValue.Value;
        end

        % Value changed function: ChipIntervalValue
        function ChipIntervalValueValueChanged(app, event)
            app.ChipIntervalValue.Value;
        end

        % Value changed function: ErrorsSDValue
        function ErrorsSDValueValueChanged(app, event)
            app.ErrorsSDValue.Value;
        end

        % Value changed function: TapsValue
        function TapsValueValueChanged(app, event)
            app.TapsValue.Value;
        end

        % Button pushed function: SimulationButton
        function SimulationButtonPushed(app, event)
clc;close all;
% Collect simulation inputs from the user
%------------------------------------------------///////////////////////////////////--------------------------------------------------------
s.PA = app.PowerAuthenticValue.Value;
s.etadB = app.PowerInteferenceValue.Value;
s.N0 = app.ThermalNoiseValue.Value;
s.Ms = app.MultiAccessValue.Value;
s.eml = app.CorrelatorDifferenceValue.Value;
s.Ta = app.AccumulationIntervalValue.Value;
s.WFE = app.BandwidthPowerValue.Value;
s.WFEbeta = app.AGCBandwidthValue.Value;
s.Pbeta = app.SetpointAGCValue.Value;
s.Tc = app.ChipIntervalValue.Value;
s.sigmaP = app.ErrorsSDValue.Value;
s.taud = app.SymmetricDifferenceValue.Value;
s.trueDelaySigma=app.TrueDelayValue.Value;
s.PNom=-140.0906; % parameter added to model
PNomLin=10^(s.PNom/10);
p.N = 1;
p.Tc = s.Tc;
Np=app.TrialsNumberValue.Value;% number of trials of theta
Nm=app.VectorsNumberValue.Value;  % for each theta number of measurement vectors simulated
pPriorClean=app.InitialCleanValue.Value;
pPriorMultipath=app.InitialMultipath.Value;
pPriorSpoof=app.InitialSpoofingValue.Value;
pPriorJam=app.InitialJammingValue.Value;
s.nTaps=app.TapsValue.Value; %select nTaps number as 5,7,15 or 41

%------------------------------------------------///////////////////////////////////--------------------------------------------------------

% \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
% 0 Begin MODE Switch between MAXIMUM LIKELIHOOD and PINCER 
% \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
switch app.SwitchMode.Value 
    % /////////////////////////////////
    % 0.1 Begin case MAXIMUM LIKELIHOOD
    % /////////////////////////////////
    case 'ML'       
mpeIn1.nTaps=s.nTaps;
mpeIn1.taps=-(s.nTaps-1)/2:(s.nTaps-1)/2;
mpeIn1.tapSize= 2/(mpeIn1.nTaps-1); % chips
mpeIn1.delayCombinations  = mpeIn1.taps' ;(-20:.01:20)';%delayGridCombinations( mpeIn.taps );
mpeIn1.invQ=inv(toeplitzQMatrix(mpeIn1.taps,mpeIn1.tapSize));
% precompute H mats
for i=1:length(mpeIn1.delayCombinations)
    delays=mpeIn1.delayCombinations(i,:);
    H=defineObservationMat(delays, mpeIn1.taps, mpeIn1.tapSize);
    mpeIn1.L(:,:,i)=pinv(H'*mpeIn1.invQ*H)*H'*mpeIn1.invQ;
    mpeIn1.Hs(:,:,i)=H;
end

% store the theta for each set of measurements
thetaKeys=zeros(1,Np); %clean->0, multipath->1, spoof->2, jam->3
% assumed prior distribution


verbose=1;
if (verbose==1)
    h=waitbar(0,'模拟正在进行中');
    app.SimulationButton.Icon = 'loading.gif';
    app.SimulationButton.Text = '模拟运行...'; 
end
for i=1:Np
    
    prc = i/Np * 100;
    app.PercentValue.Value = prc;
    
    clearvars p
    p.N = 1;
    p.Tc = s.Tc;
    testU = rand;
    
    if testU < pPriorClean
        scenario = 'CLEAN';
        thetaKeys(i)=0;
        p.i = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath
        scenario = 'MULTIPATH';
        thetaKeys(i)=1;
        p.i = 1;
        p.elDeg = raylrnd(3)+5;
        s.Pflag = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath+pPriorSpoof
        scenario = 'SPOOFING';
        p.i = 2;
        p.elDeg = 3;
        p.muEtadB =1;
        p.sigmaEtadB =1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=2;
    else
        scenario = 'JAMMING';
        p.i = 3;
        % ranging from weak jamming to severe jamming
        p.riceSdB = rand*12+3;
        p.riceSigmadB = 1;
        op = simulatePincerParameters(p);
        thetaKeys(i)=3;
    end
    
    
    if strcmp(scenario,'CLEAN')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='g.';
        
    elseif strcmp(scenario,'MULTIPATH')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='k.';
        
    elseif strcmp(scenario,'SPOOFING')
        s.WFE = 2e6;
        s.WFEbeta= 20e6;
        s.Pbeta=  -128.9726;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 2;
        s.colorSpec ='r.';
    elseif strcmp(scenario,'JAMMING')
        
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        deltaTheta=rand*2*pi;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 1;
        s.colorSpec ='b.';
           
    end
    simConfigs(i)=s;
    pincerParamsOut(i)=op;
    for j=1:Nm
        
        % save 2D arrays of the sim config and PM-ML sim output
        % i is the index of the theta selection (1 to Np)
        % j is the index of the simulated measurement for each i (1 to Nm)
        [Out(i,j)] = simulateIQTaps(s);
        mpeIn1.signalTaps=Out(i,j).signalTaps;

        
        [mpeOut1(i,j)] =  multiTapMultipathEstimatorSingleModelBisect( mpeIn1);
            
        
    end
    if(verbose==1)
        waitbar(i/Np);
    end
end
    close(h)

if (verbose==1)
    h=waitbar(0,'绘制图形结果');
    app.SimulationButton.Icon = 'loading.gif';
    app.SimulationButton.Text = '标图...';
end


% figure
MarkerSize=1;

cd1=[];
mp1=[];
sp1=[];
jm1=[];

cd2=[];
mp2=[];
sp2=[];
jm2=[];


cd1X=[];
mp1X=[];
sp1X=[];
jm1X=[];


cdP=[];
mpP=[];
spP=[];
jmP=[];


for i=1:Np
    
    if thetaKeys(i)==0
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            cd1=[cd1 mpeOut1(i,j).D_RSS];
            cd2=[cd2 mpeOut1(i,j).sigRSS];
            cdP=[cdP P_WRT_Nom];
            cd1X=[cd1X mpeOut1(i,j).chiSqr];

        end
        
    elseif thetaKeys(i)==1
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            mp1=[mp1 mpeOut1(i,j).D_RSS];
            mp2=[mp2 mpeOut1(i,j).sigRSS];
            mpP=[mpP P_WRT_Nom];
            mp1X=[mp1X mpeOut1(i,j).chiSqr];

        end
    elseif thetaKeys(i)==2
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            sp1=[sp1 mpeOut1(i,j).D_RSS];
            sp2=[sp2 mpeOut1(i,j).sigRSS];
            spP=[spP P_WRT_Nom];
            sp1X=[sp1X mpeOut1(i,j).chiSqr];
         
        end
    else
        
        for j=1:Nm
            PLinNoisey= 10^(Out(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            jm1=[jm1 mpeOut1(i,j).D_RSS];
            jm2=[jm2 mpeOut1(i,j).sigRSS];
            jmP=[jmP P_WRT_Nom];
            jm1X=[jm1X mpeOut1(i,j).chiSqr];
         
        end
    end
    
    waitbar(i/Np);
end


     % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
     % Generate new data and save a file
     % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
        switch app.DataSim.Value 
             case '生成和保存新数据'
                        save GSVET_ML_仿真数据.mat
        end
        
cla(app.UIAxesGSVET, 'reset')
%hold(app.UIAxesGSVET,'on') 

        % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
        % 0.1 Begin DIMENSION Switch between 3D and 2D
        % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
        switch app.SwitchDimension.Value 
             % //////////////////
             % 0.11 Begin case 3D
             % //////////////////
             case '3D'
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    % 0.111 Begin SIGNAL Switch for Clean (ON or OFF)
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    switch app.SwitchClean.Value 
                         % ///////////////
                         % 1 Begin case ON
                         % ///////////////
                         case '开'
                              plot3(app.UIAxesGSVET,cd1X,cdP,cd2,'o','MarkerSize', 4',...
                              'MarkerEdgeColor','green',...
                              'MarkerFaceColor',[0 0.8 0.1]);
                              hold(app.UIAxesGSVET,'on') 
                    end
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    % 0.112 Begin SIGNAL Switch for Multipath (ON or OFF)
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\                    
                   switch app.SwitchMultipath.Value 
                         % ///////////////
                         % 1 Begin case ON
                         % ///////////////
                         case '开'
                               plot3(app.UIAxesGSVET,mp1X,mpP,mp2,'diamond','MarkerSize', 4,...
                              'MarkerEdgeColor','y',...
                              'MarkerFaceColor',[0.8 0.8 0]);
                               hold(app.UIAxesGSVET,'on')                              
                   end 
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    % 0.113 Begin SIGNAL Switch for Jamming (ON or OFF)
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\                   
                   switch app.SwitchJamming.Value 
                         % ///////////////
                         % 1 Begin case ON
                         % ///////////////
                         case '开'
                               plot3(app.UIAxesGSVET,jm1,jmP,jm2,'>','MarkerSize', 4',...
                               'MarkerEdgeColor','b',...
                               'MarkerFaceColor',[0.1 0.2 0.8]);
                               hold(app.UIAxesGSVET,'on') 
                   end
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    % 0.114 Begin SIGNAL Switch for Spoofing (ON or OFF)
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\                       
                   switch app.SwitchSpoof.Value                     
                         % ///////////////
                         % 1 Begin case ON
                         % ///////////////
                         case '开'
                               plot3(app.UIAxesGSVET,sp1X,spP,sp2,'hexagram','MarkerSize', 4,...
                               'MarkerEdgeColor','red',...
                               'MarkerFaceColor',[1 .6 .6]);
                               hold(app.UIAxesGSVET,'on') 
                   end
             % //////////////////
             % 0.12 Begin case 2D
             % //////////////////             
                    case '2D'
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    % 0.121 Begin SIGNAL Switch for Clean (ON or OFF)
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    switch app.SwitchClean.Value 
                         % ///////////////
                         % Begin case ON
                         % ///////////////
                         case '开'
                              plot(app.UIAxesGSVET,cd1X,cdP,'o','MarkerSize', 4',...
                              'MarkerEdgeColor','green',...
                              'MarkerFaceColor',[0 0.8 0.1]);
                              hold(app.UIAxesGSVET,'on') 
                    end
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    % 0.122 Begin SIGNAL Switch for Multipath (ON or OFF)
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\                    
                   switch app.SwitchMultipath.Value 
                         % ///////////////
                         % 1 Begin case ON
                         % ///////////////
                         case '开'
                               plot(app.UIAxesGSVET,mp1X,mpP,'diamond','MarkerSize', 4,...
                              'MarkerEdgeColor','y',...
                              'MarkerFaceColor',[0.8 0.8 0]);
                               hold(app.UIAxesGSVET,'on')                              
                   end 
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    % 0.123 Begin SIGNAL Switch for Jamming (ON or OFF)
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\                   
                   switch app.SwitchJamming.Value 
                         % ///////////////
                         % 1 Begin case ON
                         % ///////////////
                         case '开'
                               plot(app.UIAxesGSVET,jm1,jmP,'>','MarkerSize', 4',...
                               'MarkerEdgeColor','b',...
                               'MarkerFaceColor',[0.1 0.2 0.8]);
                               hold(app.UIAxesGSVET,'on') 
                   end
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    % 0.124 Begin SIGNAL Switch for Spoofing (ON or OFF)
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\                       
                   switch app.SwitchSpoof.Value                     
                         % ///////////////
                         % 1 Begin case ON
                         % ///////////////
                         case '开'
                               plot(app.UIAxesGSVET,sp1X,spP,'hexagram','MarkerSize', 4,...
                               'MarkerEdgeColor','red',...
                               'MarkerFaceColor',[1 .6 .6]);
                               hold(app.UIAxesGSVET,'on') 
                   end
        end 
  

xlabel(app.UIAxesGSVET,'\it 信号失真','FontSize',12,'FontWeight','bold')
ylabel(app.UIAxesGSVET,'\it 功率 (dB)','FontSize',12,'FontWeight','bold')
zlabel(app.UIAxesGSVET,'\it 信号分配','FontSize',12,'FontWeight','bold')
title(app.UIAxesGSVET, '')

 if(verbose==1)
    app.SimulationButton.Text = '结果';
    app.SimulationButton.Icon = 'tick.png';
    close(h);
 end 
%------------------------------------------------///////////////////////////////////--------------------------------------------------------
    % ///////////////////////////////
    % 0.2 Begin case PINCER
    % /////////////////////////////// 
    case 'PN' 

verbose=1;
if (verbose==1)
    h=waitbar(0,'模拟正在进行中');
    app.SimulationButton.Icon = 'loading.gif';
    app.SimulationButton.Text = '模拟运行...'; 
end

for i=1:Np
    
    prc = i/Np * 100;
    app.PercentValue.Value = prc;
    
    
    clearvars p
    p.N = 1;
    p.Tc = s.Tc;
    testU = rand;
    
    if testU < pPriorClean
        scenario = 'CLEAN';
        p.i = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath
        scenario = 'MULTIPATH';
        p.i = 1;
        p.elDeg = raylrnd(3)+5;
        s.Pflag = 0;
        op = simulatePincerParameters(p);
    elseif testU < pPriorClean+pPriorMultipath+pPriorSpoof
        scenario = 'SPOOFING';
        p.i = 2;
        p.elDeg = 3;
        % ranging from matched power to moderate overpowered
        p.muEtadB =1; 
        p.sigmaEtadB =1;
        op = simulatePincerParameters(p);
    else
        scenario = 'JAMMING';
        p.i = 3;
        % ranging from weak jamming to severe
        p.riceSdB = rand*12+3;
        p.riceSigmadB = 1;
        op = simulatePincerParameters(p);
        
    end
    
    
    if strcmp(scenario,'CLEAN')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='g.';
        
        
    elseif strcmp(scenario,'MULTIPATH')
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 0;
        s.colorSpec ='k.';
        
    elseif strcmp(scenario,'SPOOFING')
        s.WFE = 2e6;
        s.WFEbeta= 20e6;
        s.Pbeta=  -128.9726;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 2;
        s.colorSpec ='r.';
    elseif strcmp(scenario,'JAMMING')
        
        s.WFE = 2e6;
        s.WFEbeta= 10e6;
        s.Pbeta=  -131.9641;
        s.etadB = op.etadB;
        s.Delta_tau = op.Delta_tau;
        s.Delta_theta = op.Delta_theta;
        s.Pflag = 1;
        s.colorSpec ='b.';  
    end
    
    obsSimConfigs(i)=s;
    thetaKeys(i)=p.i;
    pincerParamsOut(i)=op;
    for j=1:Nm
        
        % save 2D arrays of the sim config and pincer sim output
        % i is the index of the theta selection (1 to Np)
        % j is the index of the simulated measurement for each i (1 to Nm)
        [pincerObsOut(i,j)] = simulatePincerObservables(s);
        
    end
    
    if(verbose==1)
        waitbar(i/Np);
    end    
end
close(h);

     % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
     % Generate new data and save a file
     % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
        switch app.DataSim.Value 
             case '生成和保存新数据'
                save ('GSVET_PN_仿真数据.mat')
        end

   if (verbose==1)
       app.SimulationButton.Text = '标图...';
       h=waitbar(0,'绘制图形结果');
   end
   
MarkerSize=1;
cdD=[];
cdP=[];
mpD=[];
mpP=[];
spD=[];
spP=[];
jmD=[];
jmP=[];
for i=1:Np
    
    if thetaKeys(i)==0
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            cdD=[cdD (norm(pincerObsOut(i,j).d))];
            cdP=[cdP P_WRT_Nom];
        end
        
    elseif thetaKeys(i)==1
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            mpD=[mpD (norm(pincerObsOut(i,j).d))];
            mpP=[mpP P_WRT_Nom];
        end
    elseif thetaKeys(i)==2
        
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            spD=[spD (norm(pincerObsOut(i,j).d))];
            spP=[spP P_WRT_Nom];
        end
    else
        % paramSimConfig(i).i ==3
        for j=1:Nm
            PLinNoisey= 10^(pincerObsOut(i,j).P/10);
            P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
            jmD=[jmD (norm(pincerObsOut(i,j).d))];
            jmP=[jmP P_WRT_Nom];
        end
     
    end
    
    %if(verbose==1)
  %  waitbar(i/Np);
    %end
end

% ---------------Plot results
cla(app.UIAxesGSVET, 'reset')

        % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
        % 0.21 Begin DIMENSION Switch between 3D and 2D
        % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
        switch app.SwitchDimension.Value
            
             % //////////////////
             % 0.211 Begin case 3D
             % //////////////////             
                    case '3D'
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    % 0.121 Begin SIGNAL Switch for Clean (ON or OFF)
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    switch app.SwitchClean.Value 
                         % ///////////////
                         % Begin case ON
                         % ///////////////
                         case '开'
                             plot3(app.UIAxesGSVET, cdD,cdP,(cdP.*cdD.*pPriorClean),'o','MarkerSize', 4',...
                             'MarkerEdgeColor','green',...
                             'MarkerFaceColor',[0 0.8 0.1]);
                              hold(app.UIAxesGSVET,'on') 
                    end
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    % 0.212 Begin SIGNAL Switch for Multipath (ON or OFF)
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\                    
                   switch app.SwitchMultipath.Value 
                         % ///////////////
                         % 1 Begin case ON
                         % ///////////////
                         case '开'
                             plot3(app.UIAxesGSVET, mpD,mpP,(mpP.*mpD.*pPriorMultipath),'>','MarkerSize', 4',...                             
                             'MarkerEdgeColor','y',...
                             'MarkerFaceColor',[0.8 0.8 0]);
                               hold(app.UIAxesGSVET,'on')                              
                   end 
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    % 0.213 Begin SIGNAL Switch for Jamming (ON or OFF)
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\                   
                   switch app.SwitchJamming.Value 
                         % ///////////////
                         % 1 Begin case ON
                         % ///////////////
                         case '开'
                              plot3(app.UIAxesGSVET, jmD,jmP,(jmP.*jmD.*pPriorJam),'diamond','MarkerSize', 4,...                             
                              'MarkerEdgeColor','b',...
                              'MarkerFaceColor',[0.1 0.2 0.8]);                             
                               hold(app.UIAxesGSVET,'on') 
                   end
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    % 0.214 Begin SIGNAL Switch for Spoofing (ON or OFF)
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\                       
                   switch app.SwitchSpoof.Value                     
                         % ///////////////
                         % 1 Begin case ON
                         % ///////////////
                         case '开'
                              plot3(app.UIAxesGSVET, spD,spP,(spP.*spD.*pPriorSpoof),'hexagram','MarkerSize', 4,...
                             'MarkerEdgeColor','red',...
                             'MarkerFaceColor',[1 .6 .6]);
                              hold(app.UIAxesGSVET,'on') 
                   end  
                   
             % //////////////////
             % 0.22 Begin case 2D
             % //////////////////             
                    case '2D'
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    % 0.121 Begin SIGNAL Switch for Clean (ON or OFF)
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    switch app.SwitchClean.Value 
                         % ///////////////
                         % Begin case ON
                         % ///////////////
                         case '开'
                             plot(app.UIAxesGSVET, cdD,cdP,'o','MarkerSize', 4',...
                             'MarkerEdgeColor','green',...
                             'MarkerFaceColor',[0 0.8 0.1]);
                              hold(app.UIAxesGSVET,'on') 
                    end
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    % 0.221 Begin SIGNAL Switch for Multipath (ON or OFF)
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\                    
                   switch app.SwitchMultipath.Value 
                         % ///////////////
                         % 1 Begin case ON
                         % ///////////////
                         case '开'
                              plot(app.UIAxesGSVET, mpD,mpP,'>','MarkerSize', 4',...                             
                             'MarkerEdgeColor','y',...
                             'MarkerFaceColor',[0.8 0.8 0]);
                               hold(app.UIAxesGSVET,'on')                              
                   end 
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    % 0.222 Begin SIGNAL Switch for Jamming (ON or OFF)
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\                   
                   switch app.SwitchJamming.Value 
                         % ///////////////
                         % 1 Begin case ON
                         % ///////////////
                         case '开'
                             plot(app.UIAxesGSVET, jmD,jmP,'diamond','MarkerSize', 4,...                             
                              'MarkerEdgeColor','b',...
                              'MarkerFaceColor',[0.1 0.2 0.8]);                             
                               hold(app.UIAxesGSVET,'on') 
                   end
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
                    % 0.223 Begin SIGNAL Switch for Spoofing (ON or OFF)
                    % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\                       
                   switch app.SwitchSpoof.Value                     
                         % ///////////////
                         % 1 Begin case ON
                         % ///////////////
                         case '开'
                              plot(app.UIAxesGSVET, spD,spP,'hexagram','MarkerSize', 4,...
                             'MarkerEdgeColor','red',...
                             'MarkerFaceColor',[1 .6 .6]);
                              hold(app.UIAxesGSVET,'on') 
                   end
        end 
 

xlim(app.UIAxesGSVET,[0 40])
ylim(app.UIAxesGSVET,[-1 20])
xlabel(app.UIAxesGSVET,'\it 信号失真','FontSize',12,'FontWeight','bold')
ylabel(app.UIAxesGSVET,'\it 功率 (dB)','FontSize',12,'FontWeight','bold')
zlabel(app.UIAxesGSVET,'\it 信号分配','FontSize',12,'FontWeight','bold')
title(app.UIAxesGSVET, '')
 if(verbose==1)
    app.SimulationButton.Text = '结果';
    app.SimulationButton.Icon = 'tick.png';
    close(h);
 end  
end 
        end

        % Button pushed function: Evaluation
        function EvaluationButtonPushed(app, event)
%GSVET_DETECTOR EVALUATION
Np=app.TrialsNumberValue.Value;% number of trials of theta
Nm=app.VectorsNumberValue.Value;  % for each theta number of measurement vectors simulated
app.Evaluation.Icon = 'tick.png';
    
% \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
% 1 Begin MODE Switch between MAXIMUM LIKELIHOOD and PINCER detectors 
% \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
switch app.SwitchMode.Value 
    % /////////////////////////////////
    % 1.1 Begin case MAXIMUM LIKELIHOOD
    % /////////////////////////////////
    case 'ML'  

load GSVET_ML_仿真数据.mat
      
  
% -------------------------------------------------------------------------
% -------------------------------------------------------------------------
% configuration variables
% -------------------------------------------------------------------------
nTaps=15;     
% -------------------------------------------------------------------------
% variable cost in theta 2, fixed 1, misclass 0
SAVEFIGS=1;             % switch to save time history of detector optimzation
tau_eml=2/(nTaps-1);    % spacing assumed (in chips) for the symmetric difference
pow=-1.5:.3:20;        % range and resolution of power for which regions are drawn
d1=[01:5:100 101:50:500 550:500:8500];		% range and resolution of symmetric differences for which regions are drawn
[da1R,powerR]=meshgrid(d1,pow);

% assume a reasonable partition based on visual
% inspection of the scatter plot for the initial decision regions

pdmlDecision=zeros(size(da1R));
benignSpoofMat=zeros(size(da1R));

for i=1:length(pow)
    for j=1:length(d1)
        if (pow(i)<1.1&& d1(j)<30  )
            pdmlDecision(i,j)=1;
        elseif (pow(i)<1.1 && d1(j)>=30 )
            pdmlDecision(i,j)=2;
        elseif (pow(i)>=1.1&& d1(j)<50)
            pdmlDecision(i,j)=4;
        else
            pdmlDecision(i,j)=3;
        end
    end
end
% plot the initial decision regions
mymap=[0 1 0; 1 1 0; 1 0 0; 0 0 1];

%for a=0:2
a=1;
costType=a;

%initialize variables
dataKeys=zeros(Np,Nm);
powerSimObs=zeros(Np,Nm);
da0a1SimObs=zeros(Np,Nm);
thetas=zeros(Np,Nm);

deltaThetas=zeros(Np,Nm);
deltaTaus=zeros(Np,Nm);
etadBs=zeros(Np,Nm);
for i=1:Np
    for j=1:Nm
        PLinNoisey= 10^(Out(i,j).P/10);
        P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
        p=P_WRT_Nom;
        d=(mpeOut1(i,j).chiSqr);
        
        [c powBinLocation] = min(abs(pow-p));
        [c da0a1BinLocation] = min(abs(d1-d));
        
        % determine unique 1D key within the 2D grid
        dataKeys(i,j)=(da0a1BinLocation)*length(d1)+powBinLocation;
        powerSimObs(i,j)=p;
        da0a1SimObs(i,j)=d;
        % although there is only one unique theta per set of Nm obs
        % storing this way makes keying the data easier
        thetas(i,j)=thetaKeys(i);
        deltaThetas(i,j)=simConfigs(i).Delta_theta;
        deltaTaus(i,j)=simConfigs(i).Delta_tau;
        etadBs(i,j)=simConfigs(i).etadB;
        
    end
end

keysAlreadyChecked=[];
changedDecisionIndex=1;


costChange=0;
firstIteration=1;
lastPincerDecision=pdmlDecision;
% unless it is the first optimization iteration, quit optimizing if no
% changes were made in the last iteration.
while(sum(sum(pdmlDecision-lastPincerDecision))~=0 || firstIteration==1 )
    firstIteration=0;
    lastPincerDecision=pdmlDecision;
    for i=1:Np
        for j=1:Nm
            key=dataKeys(i,j);
            % only check each grid key once
            if find(key==keysAlreadyChecked)
                continue
            end
            % determine if on a decision boundary, if not, continue
            onDecisionBoundary=0;
            powLoc=mod(key,length(d1));
            
            da1Loc=floor((key)/length(d1));
            if((da1Loc>1))&& (da1Loc<length(d1)) &&( (powLoc>1)&&(powLoc<length(pow)))
                
                if powLoc<length(pow)
                    if(pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc+1,da1Loc))
                        onDecisionBoundary=1;
                    end
                end
                if powLoc<length(pow) && da1Loc<length(d1)
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc+1,da1Loc+1))
                        onDecisionBoundary=1;
                    end
                end
                if(da1Loc<length(d1))
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc,da1Loc+1))
                        onDecisionBoundary=1;
                    end
                end
                if(powLoc>1)
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc-1,da1Loc))
                        onDecisionBoundary=1;
                    end
                end
                if(da1Loc>1)
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc,da1Loc-1))
                        onDecisionBoundary=1;
                    end
                end
                if(da1Loc>1 && powLoc>1)
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc-1,da1Loc-1))
                        onDecisionBoundary=1;
                    end
                end
                if(powLoc<length(pow) && da1Loc>1)
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc+1,da1Loc-1))
                        onDecisionBoundary=1;
                    end
                end
                if(powLoc>1 && da1Loc<length(d1))
                    if (pdmlDecision(powLoc,da1Loc)~=pdmlDecision(powLoc-1,da1Loc+1))
                        onDecisionBoundary=1;
                    end
                end
            end
            
            if(onDecisionBoundary==0)
                continue
            end
            
            % keep track if this location has already been evaluated
            keysAlreadyChecked=[keysAlreadyChecked key];
            
            % evaluate cost of current decision and the cost of all the other
            % possible decisions for this  grid key location
            decisionOfGridKey=interp2(da1R,powerR,pdmlDecision,...
                da0a1SimObs(i,j),powerSimObs(i,j),'nearest');
            
            thetasInGridKey=thetas(dataKeys==key);
            etadBsinGridKey=etadBs(dataKeys==key);
            deltaTauIAsInGridKey=deltaTaus(dataKeys==key);
            deltaThetaIAsInGridKey=deltaThetas(dataKeys==key);
            
            currentCost=0;
            otherDecisions=find([1,2,3,4]~=decisionOfGridKey);
            otherDecisionCosts=[0,0,0];
            powLoc=mod(key,length(d1));
            da1Loc=floor((key)/length(d1));
            
            
            for k=1:length(thetasInGridKey)
                
                currentCost=currentCost+ pdmlCostFunctionSingle(decisionOfGridKey,...
                    thetasInGridKey(k)+1, etadBsinGridKey(k) ,deltaTauIAsInGridKey(k), ...
                    deltaThetaIAsInGridKey(k),tau_eml,costType);
                %                  benignSpoofMat(powLoc,da1Loc)=benignSpoof(decisionOfGridKey,...
                %                     thetasInGridKey(k)+1, etadBsinGridKey(k) ,deltaTauIAsInGridKey(k), ...
                %                     deltaThetaIAsInGridKey(k),tau_eml,costType);
                
                for l=1:length(otherDecisions)
                    otherDecisionCosts(l)=otherDecisionCosts(l)+ pdmlCostFunctionSingle(otherDecisions(l),...
                        thetasInGridKey(k)+1,etadBsinGridKey(k) ,...
                        deltaTauIAsInGridKey(k), deltaThetaIAsInGridKey(k),tau_eml,costType);
                end
            end
            
            % determine if cost can be reduced, if so, accept lowest cost
            if(sum(otherDecisionCosts<currentCost))
                newDecision=otherDecisions(otherDecisionCosts==min(otherDecisionCosts));
                
                % 2D grid location from key value
                powLoc=mod(key,length(d1));
                da1Loc=floor((key)/length(d1));
                
                
                disp([da1Loc powLoc])
                pdmlDecision(powLoc,da1Loc)=newDecision(1);
                changedDecisionIndex=changedDecisionIndex+1;
                
                % save the cost improvement history (for plotting)
                costChange(changedDecisionIndex)=costChange(changedDecisionIndex-1)+...
                    (currentCost-min(otherDecisionCosts));
            end
        end
    end
end
[M,N]=size(pdmlDecision);
for i=2:M-1
    for j=2:N-1
        
        decision=pdmlDecision(i,j);
        boundaryDecision=pdmlDecision(i,j+1);
        boundaryCount=0;
        if ((pdmlDecision(i,j-1)==boundaryDecision))
            boundaryCount= boundaryCount+1;
        end
        if ((pdmlDecision(i-1,j)==boundaryDecision))
            boundaryCount= boundaryCount+1;
        end
        if ((pdmlDecision(i+1,j)==boundaryDecision))
            boundaryCount= boundaryCount+1;
        end
        if (decision~=boundaryDecision)&&(boundaryCount>0)
            disp('Found Island')
            pdmlDecision(i,j)=boundaryDecision;
        end
    end
end
 
hh = figure;
                        figure('WindowStyle', 'modal', 'name', 'GSVET - ML - 探测器信号模式评估','NumberTitle',"off")
                        colormap(mymap);
                        surf(log10(d1),pow,pdmlDecision+benignSpoofMat);

                        xlabel('\it Log_10(信号失真) ','FontSize',12,'FontWeight','bold')
                        ylabel('\it 功率 (dB)','FontSize',12,'FontWeight','bold')
                        zlabel('\it 信号分配','FontSize',12,'FontWeight','bold')                  
 close(hh)                      
                        figSaveStrpng=sprintf('GSVET_ML_评价数字.png',a);
                        saveas(gcf,figSaveStrpng);
                        figSaveStrfig=sprintf('GSVET_ML_图数据信息.fig',a);
                        saveas(gcf,figSaveStrfig); 


                         if costType==0
                             save GSVET_ML_评估成本函数类型_0.mat d1 pow pdmlDecision
                             elseif costType==1
                             save GSVET_ML_评估成本函数类型_1.mat d1 pow pdmlDecision
                             elseif costType==2
                             save GSVET_ML_评估成本函数类型_2.mat d1 pow pdmlDecision
                        end
   
end


switch app.SwitchMode.Value 
    % //////////////////////
    % 1.2 Begin case PINCER
    % //////////////////////
    case 'PN' 
        
load ('GSVET_PN_仿真数据.mat')
                 
costType=0; % variable cost in theta 2, fixed 1, misclass 0
SAVEFIGS=1;
tau_eml=0.3;
power=-1.2:.35:20;
deml=0:.35:40;
[demlR,powerR]=meshgrid(deml,power);

% start a reasonable partition based on visual
% inspection of the scatter plot for initial deicison

% clean D=>[0,4) P=>[-1,1)
% multipath D=>[4,11] P=>[-1,1)
% jam  D=>[0,4) P=>[1,20]
% spoof D=>[4,35] P=>[1,20] && D=>[11,35] P=>[-1,1]

pincerDecision=zeros(size(demlR));
for i=1:length(power)
    for j=1:length(deml)
        if(power(i)<1 && deml(j)<2)
            pincerDecision(i,j)=1;
        elseif (power(i)<1)
            if(deml(j)>=2 && deml(j)<15)
                pincerDecision(i,j)=2;
            else
                pincerDecision(i,j)=2;
            end
        elseif (power(i)>=1 && power(i)<=5 && deml(j)>=6)
            pincerDecision(i,j)=3;
        elseif (power(i)>=5 && power(i)<=10 && deml(j)>=7)
            pincerDecision(i,j)=3;
        elseif (power(i)>=10 && deml(j)>=7.5)
            pincerDecision(i,j)=3;
        else
            pincerDecision(i,j)=4;
        end
    end
end

mymap=[0 1 0; 1 1 0; 1 0 0; 0 0 1];

figure('WindowStyle', 'modal', 'name', 'GSVET - PN - 探测器信号模式评估','NumberTitle',"off" )

colormap(mymap);
h=pcolor(deml,power,pincerDecision);
set(h, 'EdgeColor', 'none');
title('实时探测器评估正在进行中')
xlabel('信号失真 |D(eml)|')
ylabel('功率 (dB)')
xlim([.05 30])
ylim([-1 19.8])
set(gca,'FontSize',14)


%  go through all the simulated observations and determine the grid
%  location, or GridKey.  Keeping track of these will speed up the cost
%  optimzation
dataKeys=zeros(Np,Nm);
powerSimObs=zeros(Np,Nm);
demlSimObs=zeros(Np,Nm);
thetas=zeros(Np,Nm);

deltaThetas=zeros(Np,Nm);
deltaTaus=zeros(Np,Nm);
etadBs=zeros(Np,Nm);

for i=1:Np


    for j=1:Nm
        PLinNoisey= 10^(pincerObsOut(i,j).P/10);
        P_WRT_Nom=10*log10(PLinNoisey/PNomLin);
        p=P_WRT_Nom;
        d=norm(pincerObsOut(i,j).d);
        
        [c powBinLocation] = min(abs(power-p));
        [c demlBinLocation] = min(abs(deml-d));
        
        % determine unique 1D key within the 2D grid
        dataKeys(i,j)=(demlBinLocation)*length(deml)+powBinLocation;
        powerSimObs(i,j)=p;
        demlSimObs(i,j)=d;
        % although there is only one unique theta per set of Nm obs
        % storing this way makes keying the data easier in the next step
        thetas(i,j)=thetaKeys(i);
        deltaThetas(i,j)=obsSimConfigs(i).Delta_theta;
        deltaTaus(i,j)=obsSimConfigs(i).Delta_tau;
        etadBs(i,j)=obsSimConfigs(i).etadB;
        
    end
end

% go through all sim obs & determine the cost of the sim obs and all other sim obs of the same key
% also check cost if other hypotheses were accepted
% would the cost be reduced by accepting a different hypothesis. If so, change the
% deicison.  for efficiency, keep track of the keys that have been checked

keysAlreadyChecked=[];
changedDecisionIndex=1;

costChange=0;
firstIteration=1;
lastPincerDecision=pincerDecision;
while(sum(sum(pincerDecision-lastPincerDecision))~=0 || firstIteration==1 )
    firstIteration=0;
    lastPincerDecision=pincerDecision;
    for i=1:Np
        for j=1:Nm
            key=dataKeys(i,j);
            % only check each grid key once
            if find(key==keysAlreadyChecked)
                continue
            end
            % determine if on a decision boundary, if not, continue
            
            onDecisionBoundary=0;
            powLoc=mod(key,length(deml));
            
            demlLoc=floor((key)/length(deml));
            if((demlLoc>1))&& (demlLoc<length(deml)) &&( (powLoc>1)&&(powLoc<length(power)))
                % disp('Here')
                if powLoc<length(power)
                    if(pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc+1,demlLoc))
                        onDecisionBoundary=1;
                    end
                end
                if powLoc<length(power) && demlLoc<length(deml)
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc+1,demlLoc+1))
                        onDecisionBoundary=1;
                    end
                end
                if(demlLoc<length(deml))
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc,demlLoc+1))
                        onDecisionBoundary=1;
                    end
                end
                if(powLoc>1)
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc-1,demlLoc))
                        onDecisionBoundary=1;
                    end
                end
                if(demlLoc>1)
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc,demlLoc-1))
                        onDecisionBoundary=1;
                    end
                end
                if(demlLoc>1 && powLoc>1)
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc-1,demlLoc-1))
                        onDecisionBoundary=1;
                    end
                end
                if(powLoc<length(power) && demlLoc>1)
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc+1,demlLoc-1))
                        onDecisionBoundary=1;
                    end
                end
                if(powLoc>1 && demlLoc<length(deml))
                    if (pincerDecision(powLoc,demlLoc)~=pincerDecision(powLoc-1,demlLoc+1))
                        onDecisionBoundary=1;
                    end
                end
            end
            if(onDecisionBoundary==0)
                continue
            else
                disp('Evaluating')


            end
            
            keysAlreadyChecked=[keysAlreadyChecked key];
            
            % evaluate cost of current decision and all the others
            % for this grid key
            decisionOfGridKey=interp2(demlR,powerR,pincerDecision,...
                demlSimObs(i,j),powerSimObs(i,j),'nearest');
            
            thetasInGridKey=thetas(dataKeys==key);
            etadBsinGridKey=etadBs(dataKeys==key);
            deltaTauIAsInGridKey=deltaTaus(dataKeys==key);
            deltaThetaIAsInGridKey=deltaThetas(dataKeys==key);
            
            currentCost=0;
            otherDecisions=find([1,2,3,4]~=decisionOfGridKey);
            otherDecisionCosts=[0,0,0];
            
            for k=1:length(thetasInGridKey)
                %% this constant cost mat is what will need to be made variable
                currentCost=currentCost+pincerCostFunction(decisionOfGridKey,...
                    thetasInGridKey(k)+1, etadBsinGridKey(k) ,deltaTauIAsInGridKey(k), ...
                    deltaThetaIAsInGridKey(k),tau_eml,costType);
                
                for l=1:length(otherDecisions)
                    otherDecisionCosts(l)=otherDecisionCosts(l)+pincerCostFunction(otherDecisions(l),...
                        thetasInGridKey(k)+1,etadBsinGridKey(k) ,...
                        deltaTauIAsInGridKey(k), deltaThetaIAsInGridKey(k),tau_eml,costType);
                end
            end
            
            % determine if cost can be reduced, if so, accept lowest cost
            if(sum(otherDecisionCosts<currentCost))
                newDecision=otherDecisions(otherDecisionCosts==min(otherDecisionCosts));
                disp([j,i])
                % get grid location from key
                
                powLoc=mod(key,length(deml));
                %powLoc=powBinLoc+1;
                demlLoc=floor((key)/length(deml));
                
                %disp('Loc')
                %disp([demlLoc powLoc])
                pincerDecision(powLoc,demlLoc)=newDecision(1);
                changedDecisionIndex=changedDecisionIndex+1;
                %save the cost improvement history for plotting
                costChange(changedDecisionIndex)=costChange(changedDecisionIndex-1)+...
                    (currentCost-min(otherDecisionCosts));

                colormap(mymap);
                h=pcolor(deml,power,pincerDecision);
                set(h, 'EdgeColor', 'none');
                xlabel('信号失真 |D(eml)|')
                ylabel('功率 (dB)')
                xlim([.05 30])
                set(gca,'FontSize',14)
                title('实时探测器评估正在进行中')

                if(SAVEFIGS)
                    figSaveStr=sprintf('GSVET_PN_评价数字.png',changedDecisionIndex);
                    saveas(gcf,figSaveStr);
              
                end
    
            end         
        end
    end  
end

% eliminate potential of island decisions
[M,N]=size(pincerDecision);
for i=2:M-1
    for j=2:N-1
        
        decision=pincerDecision(i,j);
        boundaryDecision=pincerDecision(i,j+1);
        if ((pincerDecision(i,j-1)==boundaryDecision)&&...
            (pincerDecision(i-1,j)==boundaryDecision)&&...
            (pincerDecision(i+1,j)==boundaryDecision)&&...
             (decision~=boundaryDecision))
            disp('Found Island')
            pincerDecision(i,j)=boundaryDecision;
        end
    end

end
%                         prc = i/Np * 100;
%     app.PercentValue.Value = prc;

end

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             cla(app.UIAxesGSVET, 'reset')
             app.PercentValue.Value = 0;
             app.SimulationButton.Text = 'SIMULATION'; 
             app.SimulationButton.Icon = '';
             app.Evaluation.Icon = 'evaluation.png'; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigureGSVET = uifigure('Visible', 'off');
            app.UIFigureGSVET.Name = 'PD DEFENSE SOFTWARE - NUAA';
            app.PanelGraph = uipanel(app.UIFigureGSVET);
            app.UIAxesGSVET = uiaxes(app.PanelGraph);
            app.UIAxesGSVET.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesGSVET.XTickLabel = '';
            app.UIAxesGSVET.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesGSVET.YTickLabel = '';
            app.Evaluation = uibutton(app.UIFigureGSVET, 'push');
            app.Evaluation.ButtonPushedFcn = createCallbackFcn(app, @EvaluationButtonPushed, true);
            app.Evaluation.Icon = 'evaluation.png';
            app.Evaluation.Text = '探测器评估';
            app.CHINAFLAG = uiimage(app.UIFigureGSVET);
            app.CHINAFLAG.ImageSource = 'Chinese.png';
            app.USFLAG = uiimage(app.UIFigureGSVET);
            app.USFLAG.ImageClickedFcn = createCallbackFcn(app, @USFLAGImageClicked, true);
            app.USFLAG.ImageSource = 'English.jpg';
            app.Label = uilabel(app.UIFigureGSVET);
            app.Label.Text = '模拟';
            app.Switch = uiswitch(app.UIFigureGSVET, 'slider');
            app.Switch.Items = {'ENGLISH', '中文'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '中文';
            app.PercentSign = uieditfield(app.UIFigureGSVET, 'text');
            app.PercentSign.Editable = 'off';
            app.PercentSign.Tooltip = {''};
            app.PercentSign.Value = '%';
            app.PercentValue = uieditfield(app.UIFigureGSVET, 'numeric');
            app.PercentValue.ValueDisplayFormat = '%.0f';
            app.PercentValue.ValueChangedFcn = createCallbackFcn(app, @PercentValueValueChanged, true);
            app.PercentValue.Editable = 'off';
            app.Image = uiimage(app.UIFigureGSVET);
            app.Image.ImageSource = 'heading.png';
            app.PanelTitle = uipanel(app.UIFigureGSVET);
            app.GNSSSIGNALISOLATIONVIAPOWERANDDISTORTIONDYNAMICMONTECARLOLabel = uilabel(app.UIFigureGSVET);
            app.GNSSSIGNALISOLATIONVIAPOWERANDDISTORTIONDYNAMICMONTECARLOLabel.Text = 'GNSS  信  号  验  证  和  评  估  工  具  (GSVET)';
            app.PanelParaBackground = uipanel(app.UIFigureGSVET);
            app.PanelParameters = uipanel(app.PanelParaBackground);
            app.CorrelatorDifferenceValue = uieditfield(app.PanelParameters, 'numeric');
            app.CorrelatorDifferenceValue.ValueDisplayFormat = '%.2f';
            app.CorrelatorDifferenceValue.ValueChangedFcn = createCallbackFcn(app, @CorrelatorDifferenceValueValueChanged, true);
            app.CorrelatorDifferenceValue.Value = 0.3;
            app.CorrelatorDifferenceLabel = uilabel(app.PanelParameters);
            app.CorrelatorDifferenceLabel.Text = '早期/晚期相关因子差异';
            app.SymmetricDifferenceValue = uieditfield(app.PanelParameters, 'numeric');
            app.SymmetricDifferenceValue.ValueDisplayFormat = '%.2f';
            app.SymmetricDifferenceValue.ValueChangedFcn = createCallbackFcn(app, @SymmetricDifferenceValueValueChanged, true);
            app.SymmetricDifferenceValue.Value = 0.15;
            app.SymmetricDifferenceLabel = uilabel(app.PanelParameters);
            app.SymmetricDifferenceLabel.Text = '偏移对称差';
            app.AccumulationIntervalValue = uieditfield(app.PanelParameters, 'numeric');
            app.AccumulationIntervalValue.ValueDisplayFormat = '%.2f';
            app.AccumulationIntervalValue.ValueChangedFcn = createCallbackFcn(app, @AccumulationIntervalValueValueChanged, true);
            app.AccumulationIntervalValue.Value = 0.1;
            app.AccumulationIntervalLabel = uilabel(app.PanelParameters);
            app.AccumulationIntervalLabel.Text = '累积间隔（秒）';
            app.BandwidthPowerValue = uieditfield(app.PanelParameters, 'numeric');
            app.BandwidthPowerValue.ValueChangedFcn = createCallbackFcn(app, @BandwidthPowerValueValueChanged, true);
            app.BandwidthPowerValue.Value = 2000000;
            app.BandwidthPowerLabel = uilabel(app.PanelParameters);
            app.BandwidthPowerLabel.Text = '接收功率带宽 （Hz）';
            app.AGCBandwidthValue = uieditfield(app.PanelParameters, 'numeric');
            app.AGCBandwidthValue.ValueChangedFcn = createCallbackFcn(app, @AGCBandwidthValueValueChanged, true);
            app.AGCBandwidthValue.Value = 20000000;
            app.AGCBandwidthLabel = uilabel(app.PanelParameters);
            app.AGCBandwidthLabel.Text = 'AGC带宽（比例因子）';
            app.SetpointAGCValue = uieditfield(app.PanelParameters, 'numeric');
            app.SetpointAGCValue.ValueDisplayFormat = '%.4f';
            app.SetpointAGCValue.ValueChangedFcn = createCallbackFcn(app, @SetpointAGCValueValueChanged, true);
            app.SetpointAGCValue.Value = -130;
            app.SetpointAGCLabel = uilabel(app.PanelParameters);
            app.SetpointAGCLabel.Text = 'AGC 设定值 （dBW）';
            app.ChipIntervalValue = uieditfield(app.PanelParameters, 'numeric');
            app.ChipIntervalValue.ValueChangedFcn = createCallbackFcn(app, @ChipIntervalValueValueChanged, true);
            app.ChipIntervalValue.Value = 1e-06;
            app.ChipIntervalLabel = uilabel(app.PanelParameters);
            app.ChipIntervalLabel.Text = '芯片间隔（秒）';
            app.ErrorsSDValue = uieditfield(app.PanelParameters, 'numeric');
            app.ErrorsSDValue.ValueDisplayFormat = '%.2f';
            app.ErrorsSDValue.ValueChangedFcn = createCallbackFcn(app, @ErrorsSDValueValueChanged, true);
            app.ErrorsSDValue.Value = 0.4;
            app.ErrorsSDLabel = uilabel(app.PanelParameters);
            app.ErrorsSDLabel.Text = '误差标准偏差 （dB）';
            app.TrueDelayLabel = uilabel(app.PanelParameters);
            app.TrueDelayLabel.Text = '真延迟西格玛';
            app.TrueDelayValue = uieditfield(app.PanelParameters, 'numeric');
            app.TrueDelayValue.ValueDisplayFormat = '%.2f';
            app.TrueDelayValue.ValueChangedFcn = createCallbackFcn(app, @TrueDelayValueValueChanged, true);
            app.TrueDelayValue.Value = 0.03;
            app.MultiAccessLabel = uilabel(app.PanelParameters);
            app.MultiAccessLabel.Text = '多址接入信号数量';
            app.MultiAccessValue = uieditfield(app.PanelParameters, 'numeric');
            app.MultiAccessValue.ValueDisplayFormat = '%.0f';
            app.MultiAccessValue.ValueChangedFcn = createCallbackFcn(app, @MultiAccessValueValueChanged, true);
            app.MultiAccessValue.Value = 7;
            app.ThermalNoiseLabel = uilabel(app.PanelParameters);
            app.ThermalNoiseLabel.Text = '本底热噪声 （dBW/Hz）';
            app.ThermalNoiseValue = uieditfield(app.PanelParameters, 'numeric');
            app.ThermalNoiseValue.ValueDisplayFormat = '%.0f';
            app.ThermalNoiseValue.ValueChangedFcn = createCallbackFcn(app, @ThermalNoiseValueValueChanged, true);
            app.ThermalNoiseValue.Value = -204;
            app.VectorsNumberValue = uieditfield(app.PanelParameters, 'numeric');
            app.VectorsNumberValue.ValueDisplayFormat = '%.0f';
            app.VectorsNumberValue.ValueChangedFcn = createCallbackFcn(app, @VectorsNumberValueValueChanged, true);
            app.VectorsNumberValue.Value = 20;
            app.VectorsNumberLabel = uilabel(app.PanelParameters);
            app.VectorsNumberLabel.Text = '每个模拟试验的载';
            app.PowerInteferenceValue = uieditfield(app.PanelParameters, 'numeric');
            app.PowerInteferenceValue.ValueDisplayFormat = '%.0f';
            app.PowerInteferenceValue.ValueChangedFcn = createCallbackFcn(app, @PowerInteferenceValueValueChanged, true);
            app.PowerInteferenceValue.Value = 18;
            app.PowerInteferenceLabel = uilabel(app.PanelParameters);
            app.PowerInteferenceLabel.Text = '干扰信号的功率优势';
            app.PowerAuthenticValue = uieditfield(app.PanelParameters, 'numeric');
            app.PowerAuthenticValue.ValueDisplayFormat = '%.0f';
            app.PowerAuthenticValue.ValueChangedFcn = createCallbackFcn(app, @PowerAuthenticValueValueChanged, true);
            app.PowerAuthenticValue.Value = -158;
            app.PowerAuthenticLabel = uilabel(app.PanelParameters);
            app.PowerAuthenticLabel.Text = '真实信号功率';
            app.TapsValue = uieditfield(app.PanelParameters, 'numeric');
            app.TapsValue.ValueDisplayFormat = '%.0f';
            app.TapsValue.ValueChangedFcn = createCallbackFcn(app, @TapsValueValueChanged, true);
            app.TapsValue.Value = 15;
            app.Label_6 = uilabel(app.PanelParameters);
            app.Label_6.Text = '分头编号为 5、7、15 或 41';
            app.Panel = uipanel(app.UIFigureGSVET);
            app.Panel.Title = '干净的信号';
            app.SwitchClean = uiswitch(app.Panel, 'toggle');
            app.SwitchClean.Items = {'开', '关'};
            app.SwitchClean.Value = '开';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '初始清洁信号';
            app.InitialCleanValue = uieditfield(app.Panel, 'numeric');
            app.InitialCleanValue.ValueChangedFcn = createCallbackFcn(app, @InitialCleanValueValueChanged, true);
            app.InitialCleanValue.Value = 0.6;
            app.Panel_2 = uipanel(app.UIFigureGSVET);
            app.Panel_2.Title = '干扰/阻塞信号';
            app.SwitchJamming = uiswitch(app.Panel_2, 'toggle');
            app.SwitchJamming.Items = {'开', '关'};
            app.SwitchJamming.Value = '开';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '初始干扰信号';
            app.InitialJammingValue = uieditfield(app.Panel_2, 'numeric');
            app.InitialJammingValue.ValueChangedFcn = createCallbackFcn(app, @InitialJammingValueValueChanged, true);
            app.InitialJammingValue.Value = 0.15;
            app.Panel_3 = uipanel(app.UIFigureGSVET);
            app.Panel_3.Title = '多路径信号';
            app.SwitchMultipath = uiswitch(app.Panel_3, 'toggle');
            app.SwitchMultipath.Items = {'开', '关'};
            app.SwitchMultipath.Value = '开';
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = '初始多径信号';
            app.InitialMultipath = uieditfield(app.Panel_3, 'numeric');
            app.InitialMultipath.ValueChangedFcn = createCallbackFcn(app, @InitialMultipathValueChanged, true);
            app.InitialMultipath.Value = 0.2;
            app.Panel_4 = uipanel(app.UIFigureGSVET);
            app.Panel_4.Title = '欺骗信号';
            app.SwitchSpoof = uiswitch(app.Panel_4, 'toggle');
            app.SwitchSpoof.Items = {'开', '关'};
            app.SwitchSpoof.Value = '开';
            app.Label_5 = uilabel(app.Panel_4);
            app.Label_5.Text = '初始欺骗信号';
            app.InitialSpoofingValue = uieditfield(app.Panel_4, 'numeric');
            app.InitialSpoofingValue.ValueChangedFcn = createCallbackFcn(app, @InitialSpoofingValueValueChanged, true);
            app.InitialSpoofingValue.Value = 0.05;
            app.PanelModes = uipanel(app.UIFigureGSVET);
            app.SwitchDimension = uiswitch(app.PanelModes, 'slider');
            app.SwitchDimension.Items = {'2D', '3D'};
            app.SwitchDimension.Value = '3D';
            app.SwitchMode = uiswitch(app.PanelModes, 'slider');
            app.SwitchMode.Items = {'ML', 'PN'};
            app.SwitchMode.Value = 'PN';
            app.SimulationButton = uibutton(app.UIFigureGSVET, 'push');
            app.SimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationButtonPushed, true);
            app.SimulationButton.Text = '开始模拟';
            app.TrialsNumberValue = uieditfield(app.UIFigureGSVET, 'numeric');
            app.TrialsNumberValue.ValueDisplayFormat = '%.0f';
            app.TrialsNumberValue.ValueChangedFcn = createCallbackFcn(app, @TrialsNumberValueValueChanged, true);
            app.TrialsNumberValue.Value = 200000;
            app.TrialsNumberLabel = uilabel(app.UIFigureGSVET);
            app.TrialsNumberLabel.Text = {'试验次数'; ''};
            app.DataBackground = uibutton(app.UIFigureGSVET, 'push');
            app.DataBackground.Text = '';
            app.DataSim = uiswitch(app.UIFigureGSVET, 'slider');
            app.DataSim.Items = {'生成和保存新数据', '维护保存的数据'};
            app.DataSim.Value = '生成和保存新数据';
            app.Button = uibutton(app.UIFigureGSVET, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Clear.png';
            app.Button.Text = '清除所有内容';
            app.NewDataImage = uiimage(app.UIFigureGSVET);
            app.NewDataImage.ImageSource = 'data.png';
            app.SavedDataImage = uiimage(app.UIFigureGSVET);
            app.SavedDataImage.ImageSource = 'files.png';
            % Show the figure after all components are created
            app.UIFigureGSVET.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GSVET_CHINESE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigureGSVET)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigureGSVET)
        end
    end
end